classdef Photo_Research < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        UpdateSetupButton               matlab.ui.control.Button
        ShutterModeSwitch               matlab.ui.control.Switch
        ShutterModeSwitch_2Label        matlab.ui.control.Label
        Addon3EditField                 matlab.ui.control.EditField
        Addon3EditField_2Label          matlab.ui.control.Label
        Addon2EditField                 matlab.ui.control.EditField
        Addon2EditField_2Label          matlab.ui.control.Label
        Addon1EditField                 matlab.ui.control.EditField
        Addon1EditField_2Label          matlab.ui.control.Label
        PrimaryAccessoryEditField       matlab.ui.control.EditField
        PrimaryAccessoryEditField_2Label  matlab.ui.control.Label
        MeasurementLabelEditField       matlab.ui.control.EditField
        MeasurementLabelEditField_2Label  matlab.ui.control.Label
        ContrastEditField               matlab.ui.control.NumericEditField
        ContrastEditField_2Label        matlab.ui.control.Label
        BrightnessEditField             matlab.ui.control.NumericEditField
        BrightnessEditField_2Label      matlab.ui.control.Label
        ExposureTimemsEditField         matlab.ui.control.NumericEditField
        ExposureTimemsEditField_2Label  matlab.ui.control.Label
        CyclestoAverageEditField        matlab.ui.control.NumericEditField
        CyclestoAverageEditField_2Label  matlab.ui.control.Label
        SyncFrequencyHzEditField        matlab.ui.control.NumericEditField
        SyncFrequencyHzEditField_2Label  matlab.ui.control.Label
        SyncModeDropDown                matlab.ui.control.DropDown
        SyncModeDropDown_2Label         matlab.ui.control.Label
        UnitSystemSwitch                matlab.ui.control.Switch
        UnitSystemSwitch_2Label         matlab.ui.control.Label
        CIEObserverSwitch               matlab.ui.control.Switch
        CIEObserverSwitch_2Label        matlab.ui.control.Label
        EchoModeSwitch                  matlab.ui.control.Switch
        EchoModeSwitch_2Label           matlab.ui.control.Label
        TimeoutsEditField               matlab.ui.control.NumericEditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        SoftwareversionTextArea         matlab.ui.control.TextArea
        SoftwareversionTextAreaLabel    matlab.ui.control.Label
        SerialnoTextArea                matlab.ui.control.TextArea
        SerialnoTextAreaLabel           matlab.ui.control.Label
        ModelTextArea                   matlab.ui.control.TextArea
        ModelTextAreaLabel              matlab.ui.control.Label
        SerialportDropDown              matlab.ui.control.DropDown
        SerialportDropDownLabel         matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        CheckstatusButton               matlab.ui.control.Button
        ClearerrorButton                matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.StateButton
        MeasurementTab                  matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        CollectButton                   matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        MeasurementEditField            matlab.ui.control.NumericEditField
        MeasurementEditFieldLabel       matlab.ui.control.Label
        DataDropDown                    matlab.ui.control.DropDown
        DataDropDownLabel               matlab.ui.control.Label
        CommandDropDown                 matlab.ui.control.DropDown
        CommandDropDownLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Camera % serial object
        Data % struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SerialportDropDown
        function SerialportDropDownOpening(app, event)
            app.SerialportDropDown.Items = cellstr(serialportlist("available"));
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            do_connect = app.ConnectButton.Value;
            port = app.SerialportDropDown.Value;
            timeout = app.TimeoutsEditField.Value;
            if do_connect
                app.Camera = initialize_camera(port,timeout);
                app.Data = struct();
                [app.Data,~] = send_command(app.Camera,app.Data,'M',601,[],[],[]);
                app.PrimaryAccessoryEditField.Value = app.Data.primary;
                app.Addon1EditField.Value = app.Data.addon{1};
                app.Addon2EditField.Value = app.Data.addon{2};
                app.Addon3EditField.Value = app.Data.addon{3};
                app.ExposureTimemsEditField.Value = app.Data.exposure_time;
                app.SyncModeDropDown.Value = app.Data.sync_mode;
                app.SyncFrequencyHzEditField.Value = app.Data.sync_freq;
                app.CyclestoAverageEditField.Value = app.Data.cycles;
                app.UnitSystemSwitch.Value = app.Data.unit_sys;
                app.CIEObserverSwitch.Value = app.Data.observer;
                [app.Data,~] = send_command(app.Camera,app.Data,'D',111,[],[],[]);
                app.ModelTextArea.Value = app.Data.model;
                [app.Data,~] = send_command(app.Camera,app.Data,'D',110,[],[],[]);
                app.SerialnoTextArea.Value = app.Data.serial_num;
                [app.Data,~] = send_command(app.Camera,app.Data,'D',114,[],[],[]);
                app.SoftwareversionTextArea.Value = app.Data.software_version;
                CheckstatusButtonPushed(app,event);
            elseif ~isempty(app.Camera)
                send_command(app.Camera,[],'Q',[],[],[],[]);
                app.Camera = [];
            end
        end

        % Button pushed function: ClearerrorButton
        function ClearerrorButtonPushed(app, event)
            send_command(app.Camera,[],'C',[],[],[],[]);
            CheckstatusButtonPushed(app,event);
        end

        % Button pushed function: CheckstatusButton
        function CheckstatusButtonPushed(app, event)
            [~,error_code] = send_command(app.Camera,[],'I',[],[],[],[]);
            status_msg = split(read_code('Error',error_code),'\n');
            app.StatusTextArea.Value = '';
            for line = 1:length(status_msg)
                app.StatusTextArea.Value{line} = status_msg{line};
            end
        end

        % Button pushed function: UpdateSetupButton
        function UpdateSetupButtonPushed(app, event)
            app.Camera.Timeout = app.TimeoutsEditField.Value;
            send_command(app.Camera,[],'B',[],[],[],app.BrightnessEditField.Value);
            send_command(app.Camera,[],'X',[],[],[],app.ContrastEditField.Value);
            send_command(app.Camera,[],'L',[],[],[],app.MeasurementLabelEditField.Value);
            send_command(app.Camera,[],'SP',[],[],[],get_code('Primary',app.PrimaryAccessoryEditField.Value));
            send_command(app.Camera,[],'SA',[],[],[],get_code('Addon',app.Addon1EditField.Value));
            send_command(app.Camera,[],'SB',[],[],[],get_code('Addon',app.Addon2EditField.Value));
            send_command(app.Camera,[],'SC',[],[],[],get_code('Addon',app.Addon3EditField.Value));
            send_command(app.Camera,[],'SO',[],[],[],get_code('CIE Observer',app.CIEObserverSwitch.Value));
            send_command(app.Camera,[],'SU',[],[],[],get_code('Unit System',app.UnitSystemSwitch.Value));
            send_command(app.Camera,[],'SN',[],[],[],app.CyclestoAverageEditField.Value);
            send_command(app.Camera,[],'SZ',[],[],[],get_code('Shutter Mode',app.ShutterModeSwitch.Value));
            send_command(app.Camera,[],'SS',[],[],[],get_code('Sync Mode',app.SyncModeDropDown.Value));
            if strcmp(app.SyncModeDropDown.Value,'User Frequency')
                send_command(app.Camera,[],'SK',[],[],[],app.SyncFrequencyHzEditField.Value);
            end
            if ~strcmp(app.Data.exposure_mode,'Adaptive')
                send_command(app.Camera,[],'SE',[],[],[],app.ExposureTimemsEditField.Value);
            end
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: EchoModeSwitch
        function EchoModeSwitchValueChanged(app, event)
            send_command(app.Camera,[],'E',[],[],[],[]);
            CheckstatusButtonPushed(app,event);
        end

        % Button pushed function: CollectButton
        function CollectButtonPushed(app, event)
            [app.Data,~] = send_command(app.Camera,app.Data,app.CommandDropDown.Value(1),get_code('Data',app.DataDropDown.Value),app.MeasurementEditField.Value,app.FilenameEditField.Value,[]);
            CheckstatusButtonPushed(app,event);
            cla(app.UIAxes);
            [fig,ax] = plot_data(app.Data,app.DataDropDown.Value);
            copyobj(ax.Children, app.UIAxes);
            app.UIAxes.XLabel = ax.XLabel;
            app.UIAxes.YLabel = ax.YLabel;
            app.UIAxes.Title = ax.Title;
            delete(fig);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectButton = uibutton(app.SetupTab, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ClearerrorButton = uibutton(app.SetupTab, 'push');
            app.ClearerrorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearerrorButtonPushed, true);
            app.ClearerrorButton.Text = 'Clear error';
            app.CheckstatusButton = uibutton(app.SetupTab, 'push');
            app.CheckstatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckstatusButtonPushed, true);
            app.CheckstatusButton.Text = 'Check status';
            app.StatusTextArea = uitextarea(app.SetupTab);
            app.SerialportDropDownLabel = uilabel(app.SetupTab);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.SetupTab);
            app.SerialportDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialportDropDownOpening, true);
            app.ModelTextAreaLabel = uilabel(app.SetupTab);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.SetupTab);
            app.SerialnoTextAreaLabel = uilabel(app.SetupTab);
            app.SerialnoTextAreaLabel.Text = 'Serial no.';
            app.SerialnoTextArea = uitextarea(app.SetupTab);
            app.SoftwareversionTextAreaLabel = uilabel(app.SetupTab);
            app.SoftwareversionTextAreaLabel.Text = 'Software version';
            app.SoftwareversionTextArea = uitextarea(app.SetupTab);
            app.TimeoutsEditFieldLabel = uilabel(app.SetupTab);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.SetupTab, 'numeric');
            app.EchoModeSwitch_2Label = uilabel(app.SetupTab);
            app.EchoModeSwitch_2Label.Text = 'Echo Mode';
            app.EchoModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.EchoModeSwitch.Items = {'Disabled', 'Enabled'};
            app.EchoModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EchoModeSwitchValueChanged, true);
            app.EchoModeSwitch.Value = 'Disabled';
            app.CIEObserverSwitch_2Label = uilabel(app.SetupTab);
            app.CIEObserverSwitch_2Label.Text = 'CIE Observer';
            app.CIEObserverSwitch = uiswitch(app.SetupTab, 'slider');
            app.CIEObserverSwitch.Items = {'2 deg', '10 deg'};
            app.CIEObserverSwitch.Value = '2 deg';
            app.UnitSystemSwitch_2Label = uilabel(app.SetupTab);
            app.UnitSystemSwitch_2Label.Text = 'Unit System';
            app.UnitSystemSwitch = uiswitch(app.SetupTab, 'slider');
            app.UnitSystemSwitch.Items = {'English', 'Metric'};
            app.UnitSystemSwitch.Value = 'Metric';
            app.SyncModeDropDown_2Label = uilabel(app.SetupTab);
            app.SyncModeDropDown_2Label.Text = 'Sync Mode';
            app.SyncModeDropDown = uidropdown(app.SetupTab);
            app.SyncModeDropDown.Items = {'No Sync', 'Auto Sync', 'User Frequency'};
            app.SyncModeDropDown.Value = 'Auto Sync';
            app.SyncFrequencyHzEditField_2Label = uilabel(app.SetupTab);
            app.SyncFrequencyHzEditField_2Label.Text = 'Sync Frequency (Hz)';
            app.SyncFrequencyHzEditField = uieditfield(app.SetupTab, 'numeric');
            app.SyncFrequencyHzEditField.AllowEmpty = 'on';
            app.SyncFrequencyHzEditField.Value = [];
            app.CyclestoAverageEditField_2Label = uilabel(app.SetupTab);
            app.CyclestoAverageEditField_2Label.Text = 'Cycles to Average';
            app.CyclestoAverageEditField = uieditfield(app.SetupTab, 'numeric');
            app.CyclestoAverageEditField.Limits = [1 99];
            app.CyclestoAverageEditField.RoundFractionalValues = 'on';
            app.CyclestoAverageEditField.Value = 1;
            app.ExposureTimemsEditField_2Label = uilabel(app.SetupTab);
            app.ExposureTimemsEditField_2Label.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.SetupTab, 'numeric');
            app.ExposureTimemsEditField.Limits = [3 6000];
            app.ExposureTimemsEditField.AllowEmpty = 'on';
            app.ExposureTimemsEditField.Value = [];
            app.BrightnessEditField_2Label = uilabel(app.SetupTab);
            app.BrightnessEditField_2Label.Text = 'Brightness (%)';
            app.BrightnessEditField = uieditfield(app.SetupTab, 'numeric');
            app.BrightnessEditField.Limits = [0 100];
            app.BrightnessEditField.Value = 100;
            app.ContrastEditField_2Label = uilabel(app.SetupTab);
            app.ContrastEditField_2Label.Text = 'Contrast (%)';
            app.ContrastEditField = uieditfield(app.SetupTab, 'numeric');
            app.ContrastEditField.Limits = [0 100];
            app.ContrastEditField.Value = 50;
            app.MeasurementLabelEditField_2Label = uilabel(app.SetupTab);
            app.MeasurementLabelEditField_2Label.Text = 'Measurement Label';
            app.MeasurementLabelEditField = uieditfield(app.SetupTab, 'text');
            app.MeasurementLabelEditField.CharacterLimits = [0 20];
            app.PrimaryAccessoryEditField_2Label = uilabel(app.SetupTab);
            app.PrimaryAccessoryEditField_2Label.Text = 'Primary Accessory';
            app.PrimaryAccessoryEditField = uieditfield(app.SetupTab, 'text');
            app.Addon1EditField_2Label = uilabel(app.SetupTab);
            app.Addon1EditField_2Label.Text = 'Addon 1';
            app.Addon1EditField = uieditfield(app.SetupTab, 'text');
            app.Addon2EditField_2Label = uilabel(app.SetupTab);
            app.Addon2EditField_2Label.Text = 'Addon 2';
            app.Addon2EditField = uieditfield(app.SetupTab, 'text');
            app.Addon3EditField_2Label = uilabel(app.SetupTab);
            app.Addon3EditField_2Label.Text = 'Addon 3';
            app.Addon3EditField = uieditfield(app.SetupTab, 'text');
            app.ShutterModeSwitch_2Label = uilabel(app.SetupTab);
            app.ShutterModeSwitch_2Label.Text = 'Shutter Mode';
            app.ShutterModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.ShutterModeSwitch.Items = {'Close after measurement', 'Always open'};
            app.ShutterModeSwitch.Value = 'Close after measurement';
            app.UpdateSetupButton = uibutton(app.SetupTab, 'push');
            app.UpdateSetupButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSetupButtonPushed, true);
            app.UpdateSetupButton.Text = 'Update Setup';
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.UIAxes = uiaxes(app.MeasurementTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CommandDropDownLabel = uilabel(app.MeasurementTab);
            app.CommandDropDownLabel.Text = 'Command';
            app.CommandDropDown = uidropdown(app.MeasurementTab);
            app.CommandDropDown.Items = {'Measure', 'Download', 'Recall'};
            app.CommandDropDown.Value = 'Measure';
            app.DataDropDownLabel = uilabel(app.MeasurementTab);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.MeasurementTab);
            app.DataDropDown.Items = {'Spectrum', 'CIE 1931 tristimulus', 'CIE 1931 x, y', 'CIE 1960 u, v', 'CIE 1976 u'', v''', 'Raw light per pixel', 'Raw dark per pixel'};
            app.DataDropDown.Value = 'Spectrum';
            app.MeasurementEditFieldLabel = uilabel(app.MeasurementTab);
            app.MeasurementEditFieldLabel.Text = 'Measurement #';
            app.MeasurementEditField = uieditfield(app.MeasurementTab, 'numeric');
            app.MeasurementEditField.Limits = [1 99];
            app.MeasurementEditField.RoundFractionalValues = 'on';
            app.MeasurementEditField.AllowEmpty = 'on';
            app.MeasurementEditField.Value = [];
            app.FilenameEditFieldLabel = uilabel(app.MeasurementTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.MeasurementTab, 'text');
            app.FilenameEditField.CharacterLimits = [0 24];
            app.CollectButton = uibutton(app.MeasurementTab, 'push');
            app.CollectButton.ButtonPushedFcn = createCallbackFcn(app, @CollectButtonPushed, true);
            app.CollectButton.Text = 'Collect';
            app.ExportButton = uibutton(app.MeasurementTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Photo_Research

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end